classdef CP < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        PowerSpectrumButton             matlab.ui.control.Button
        Image2                          matlab.ui.control.Image
        Image                           matlab.ui.control.Image
        MorphologicalfilterButton       matlab.ui.control.Button
        ChoosedatasetButton             matlab.ui.control.Button
        ChooseRowDropDown               matlab.ui.control.DropDown
        ChooseRowDropDownLabel          matlab.ui.control.Label
        DiscreteWaveletTransformButton  matlab.ui.control.Button
        TimeDomainSignalAndFrequencyDomainButton  matlab.ui.control.Button
        BandsButton                     matlab.ui.control.Button
        filterSignalButton              matlab.ui.control.Button
        RowSignalButton                 matlab.ui.control.Button
        EditField                       matlab.ui.control.EditField
        Label                           matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
         numRows
         EEG_Data
         rowNum
         rowData
         UIAxes1       matlab.ui.control.UIAxes
         UIAxes2       matlab.ui.control.UIAxes
         UIAxes3       matlab.ui.control.UIAxes
         UIAxes4       matlab.ui.control.UIAxes
         UIAxes5       matlab.ui.control.UIAxes
         UIAxes6       matlab.ui.control.UIAxes
         
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RowSignalButton
        function RowSignalButtonPushed(app, event)
         % Generate the sine signal for the selected row
         % Get the selected row number from the dropdow
           
     delete(app.UIAxes);
            delete(app.UIAxes1);
            delete(app.UIAxes2);
            delete(app.UIAxes3);
            delete(app.UIAxes4);
            delete(app.UIAxes5);
            delete(app.UIAxes6);
            
            Fs=1000;
            L = length(app.EEG_Data);
            time = 0:1/Fs:(L-1)/Fs;
          app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.Position = [258 16 903 610];

        app.rowData = app.EEG_Data(app.rowNum,:);
        % Clear the UIAxes and plot the signal
        cla(app.UIAxes);
        plot(app.UIAxes,time, app.EEG_Data);
        
        % Set the UIAxes properties
        title(app.UIAxes, 'Original Signal');
        xlabel(app.UIAxes, 'Time');
        ylabel(app.UIAxes, 'Amplitude');
        end

        % Button pushed function: filterSignalButton
        function filterSignalButtonPushed(app, event)
           % Remove the existing UIAxes
       delete(app.UIAxes);
            delete(app.UIAxes1);
            delete(app.UIAxes2);
            delete(app.UIAxes3);
            delete(app.UIAxes4);
            delete(app.UIAxes5);
            delete(app.UIAxes6);
            
            app.rowData = app.EEG_Data(app.rowNum,:);
            Fs=1000;
            L = length(app.EEG_Data);
            time = 0:1/Fs:(L-1)/Fs;
            % Create two subplots
            app.UIAxes1 = uiaxes(app.UIFigure);
            title(app.UIAxes1, 'EEG Signal after High-pass Filtering')
            xlabel(app.UIAxes1, 'Time (s)')
            ylabel(app.UIAxes1, 'Amplitude (uV)')
           
            app.UIAxes1.Position = [258 330 903 310];
        
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'EEG Signal after Low-pass Filtering')
            xlabel(app.UIAxes2, 'Time (s)')
            ylabel(app.UIAxes2, 'Amplitude (uV)')
           
            app.UIAxes2.Position = [258 10 903 310];
            
            % High-pass filter
            HPF = 0.5; % High-pass filter cutoff frequency
            [b,a] = butter(4, HPF/(Fs/2), 'high');  
            EEG_Data_HPF = filtfilt(b,a,app.rowData);  
            
            % Low-pass filter   
            LPF = 10; % Low-pass filter cutoff frequency   
            [b,a] = butter(4, LPF/(Fs/2), 'low');   
            EEG_Data_LPF = filtfilt(b,a,app.rowData);   
                 
            % Plot the filtered signals    
            
            plot(app.UIAxes1,time, EEG_Data_HPF);     
            plot(app.UIAxes2,time, EEG_Data_LPF); 
            app.UIFigure.Units = 'normalized';
            
             
        end

        % Button pushed function: ChoosedatasetButton
        function ChoosedatasetButtonPushed(app, event)
         [filename, pathname] = uigetfile({'*.mat';}, 'Select Dataset File');
            [~, ~, fileExt] = fileparts(filename);
            if strcmpi(fileExt, '.mat')
                data = load(fullfile(pathname, filename));
            end
            app.EEG_Data=data.val;
            app.numRows = height(data.val);
        end

        % Clicked callback: ChooseRowDropDown
        function ChooseRowDropDownClicked(app, event)
 
    % Create new options
    options = cell(app.numRows, 1);
    for i = 1:app.numRows
        options{i} = sprintf('Rows %d', i);
    end
     app.ChooseRowDropDown.Items = options;
     app.rowNum= str2double(app.ChooseRowDropDown.Value(6:end));
     display(app.rowNum);
        end

        % Button pushed function: BandsButton
        function bands(app, event)
            delete(app.UIAxes);
             delete(app.UIAxes1); delete(app.UIAxes2);
            app.rowData = app.EEG_Data(app.rowNum,:);
            Fs=1000;
            L = length(app.EEG_Data);
            time = 0:1/Fs:(L-1)/Fs;
            % Create two subplots
      delete(app.UIAxes);
            delete(app.UIAxes1);
            delete(app.UIAxes2);
            delete(app.UIAxes3);
            delete(app.UIAxes4);
            delete(app.UIAxes5);
            delete(app.UIAxes6);
            
            % Create UIAxes objects
            app.UIAxes1 = uiaxes(app.UIFigure);
            title(app.UIAxes1, 'Delta Band  (Deep rest , dreamless sleep)')
            xlabel(app.UIAxes1, 'Time (s)')
            ylabel(app.UIAxes1, 'Amplitude (uV)  ')
            app.UIAxes1.Position = [258 500 903 130];
            
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Theta Band  (Deeply relaxed)')
            xlabel(app.UIAxes2, 'Time (s)')
            ylabel(app.UIAxes2, 'Amplitude (uV)')
            app.UIAxes2.Position = [258 350 903 130];
            
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Alpha Band  (Day dream )')
            xlabel(app.UIAxes3, 'X-axis label')
            ylabel(app.UIAxes3, 'Y-axis label')
            app.UIAxes3.Position = [258 200 903 130];
            
            app.UIAxes4 = uiaxes(app.UIFigure);
            title(app.UIAxes4, 'Beta Band  (alert, active thinking, panic attack)')
            xlabel(app.UIAxes4, 'X-axis label')
            ylabel(app.UIAxes4, 'Y-axis label')
            app.UIAxes4.Position = [258 50 445 130];
            
            app.UIAxes5 = uiaxes(app.UIFigure);
            title(app.UIAxes5, 'Gamma Band  (Attention)')
            xlabel(app.UIAxes5, 'X-axis label')
            ylabel(app.UIAxes5, 'Y-axis label')
            app.UIAxes5.Position = [720 50 445 130];
            fs = 256; % Sampling frequency of EEG signal

% Define the frequency bands of interest
delta_band = [0.5, 4];
theta_band=[4,8];
alpha_band = [8, 13];
beta_band=[13,30];
gamma_band=[30,100];
            t = 0:1/fs:(length(app.rowData)-1)/fs; % Time vector for EEG signal

    % Calculate the power spectral density (PSD) of the EEG signal
    nfft = 2^nextpow2(length(app.rowData)); % Length of FFT
    psd = abs(fft(app.rowData, nfft)).^2/length(app.rowData)/fs; % Power spectral density
    f = fs/2*linspace(0,1,nfft/2+1); % Frequency vector for PSD

    % Identify the frequency bins corresponding to the frequency bands of interest
    delta_bin = [find(f>=delta_band(1),1), find(f<=delta_band(2),1,'last')];
    theta_bin = [find(f>=theta_band(1),1), find(f<=theta_band(2),1,'last')];
    alpha_bin = [find(f>=alpha_band(1),1), find(f<=alpha_band(2),1,'last')];
    beta_bin = [find(f>=beta_band(1),1), find(f<=beta_band(2),1,'last')];
    gamma_bin = [find(f>=gamma_band(1),1), find(f<=gamma_band(2),1,'last')];

    % Extract the spectral power in each frequency band
    delta_power = psd(delta_bin(1):delta_bin(2));
    theta_power = psd(theta_bin(1):theta_bin(2));
    alpha_power = psd(alpha_bin(1):alpha_bin(2));
    beta_power = psd(beta_bin(1):beta_bin(2));
    gamma_power = psd(gamma_bin(1):gamma_bin(2));


plot(app.UIAxes1,f(delta_bin(1):delta_bin(2)), delta_power); 
plot(app.UIAxes2,f(theta_bin(1):theta_bin(2)), theta_power); 
plot(app.UIAxes3,f(alpha_bin(1):alpha_bin(2)), alpha_power); 
plot(app.UIAxes4,f(beta_bin(1):beta_bin(2)), beta_power); 
plot(app.UIAxes5,f(gamma_bin(1):gamma_bin(2)), gamma_power); 

        end

        % Button pushed function: TimeDomainSignalAndFrequencyDomainButton
        function timeDomandFreaDom(app, event)
            
            % Remove the existing UIAxes
            delete(app.UIAxes);
            delete(app.UIAxes1);
            delete(app.UIAxes2);
            delete(app.UIAxes3);
            delete(app.UIAxes4);
            delete(app.UIAxes5);
              delete(app.UIAxes6);
            app.rowData = app.EEG_Data(app.rowNum,:);
            Fs = 1000; % Sampling frequency
            L = length(app.EEG_Data); % Length of signal
            time = 0:1/Fs:(L-1)/Fs;
            f = Fs*(0:(L/2))/L;
            Y = fft(app.rowData);
            P2 = abs(Y/L);
            P1 = P2(1:L/2+1);
             
            % Create two subplots
            app.UIAxes1 = uiaxes(app.UIFigure);
            title(app.UIAxes1, 'EEG Signal in Time Domain')
            xlabel(app.UIAxes1, 'Time (s)')
            ylabel(app.UIAxes1, 'Amplitude (uV)')
           
            app.UIAxes1.Position = [258 330 903 310];
        
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'EEG Signal in Frequency Domain')
            xlabel(app.UIAxes2, 'Time (s)')
            ylabel(app.UIAxes2, 'Amplitude (uV)')
           
            app.UIAxes2.Position = [258 10 903 310];
           
            plot(app.UIAxes1,time, app.rowData);     
            plot(app.UIAxes2,f,P1); 
            app.UIFigure.Units = 'normalized';
            
        end

        % Button pushed function: DiscreteWaveletTransformButton
        function DWT(app, event)
            delete(app.UIAxes);
             delete(app.UIAxes1); delete(app.UIAxes2);
            app.rowData = app.EEG_Data(app.rowNum,:);
            Fs=1000;
            L = length(app.EEG_Data);
            time = 0:1/Fs:(L-1)/Fs;
            % Create two subplots
           % Remove existing axes
           delete(app.UIAxes);
            delete(app.UIAxes1);
            delete(app.UIAxes2);
            delete(app.UIAxes3);
            delete(app.UIAxes4);
            delete(app.UIAxes5);
            delete(app.UIAxes6);
            
            % Create UIAxes objects
            app.UIAxes1 = uiaxes(app.UIFigure);
            title(app.UIAxes1, 'Approximation Coefficients')
            xlabel(app.UIAxes1, 'Time (s)')
            ylabel(app.UIAxes1, 'Amplitude (uV)')
            app.UIAxes1.Position = [258 500 903 130];
            
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Level 1 Detail Coefficients')
            xlabel(app.UIAxes2, 'Time (s)')
            ylabel(app.UIAxes2, 'Amplitude (uV)')
            app.UIAxes2.Position = [258 350 903 130];
            
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Level 2 Detail Coefficients')
            xlabel(app.UIAxes3, 'X-axis label')
            ylabel(app.UIAxes3, 'Y-axis label')
            app.UIAxes3.Position = [258 200 445 130];
            
         
            app.UIAxes4 = uiaxes(app.UIFigure);
            title(app.UIAxes4, 'Level 3 Detail Coefficients')
            xlabel(app.UIAxes4, 'X-axis label')
            ylabel(app.UIAxes4, 'Y-axis label')
            app.UIAxes4.Position = [720 200 445 130];
           
            
            app.UIAxes5 = uiaxes(app.UIFigure);
            title(app.UIAxes5, 'Level 4 Detail Coefficients')
            xlabel(app.UIAxes5, 'X-axis label')
            ylabel(app.UIAxes5, 'Y-axis label')
            app.UIAxes5.Position = [258 50 445 130];
            
          app.UIAxes6 = uiaxes(app.UIFigure);
            title(app.UIAxes6, 'Level 5 Detail Coefficients')
            xlabel(app.UIAxes6, 'X-axis label')
            ylabel(app.UIAxes6, 'Y-axis label')
            app.UIAxes6.Position = [720 50 445 130];
            

[c,l] = wavedec(app.rowData,5,'db4');
approx = appcoef(c,l,'db4');
[cd1,cd2,cd3,cd4,cd5] = detcoef(c,l,[1 2 3 4 5]);
   

plot(app.UIAxes1,approx); 
plot(app.UIAxes2,cd1); 
plot(app.UIAxes3,cd2); 
plot(app.UIAxes4,cd3); 
plot(app.UIAxes5,cd4); 
plot(app.UIAxes6,cd5); 

        end

        % Button pushed function: MorphologicalfilterButton
        function MorphologicalfilterButtonPushed(app, event)
              delete(app.UIAxes);
            delete(app.UIAxes1);
            delete(app.UIAxes2);
            delete(app.UIAxes3);
            delete(app.UIAxes4);
            delete(app.UIAxes5);
            delete(app.UIAxes6);


             % Create two subplots
            app.UIAxes1 = uiaxes(app.UIFigure);
            title('Original EEG Signal');
xlabel('Time (s)');
ylabel('Amplitude (\muV)');
           
            app.UIAxes1.Position = [258 330 903 310];
        
            app.UIAxes2 = uiaxes(app.UIFigure);
           title('Filtered EEG Signal with Detected Spikes');
              xlabel('Time (s)');
               ylabel('Amplitude (\muV)');
           
            app.UIAxes2.Position = [258 10 903 310];

            % Apply high-pass filter to remove low-frequency noise
fs = 1000; % Sampling frequency
fc = 5; % Cutoff frequency
[b, a] = butter(4, fc/(fs/2), 'high');
eeg_filtered = filter(b, a, app.rowData);

% Apply morphological filter to enhance spikes
se = strel('line', 10, 0); % Structuring element for morphological filter
eeg_morph = imtophat(eeg_filtered, se); % Morphological filter

% Detect spikes in the filtered signal
threshold = 3*std(eeg_morph); % Threshold for spike detection
spike_indices = find(eeg_morph > threshold); % Spike indices

t = (0:length(app.rowData)-1)/fs; % Time vector
plot(app.UIAxes1,t, app.rowData);
plot(app.UIAxes2,t, eeg_morph);
 

        end

        % Button pushed function: PowerSpectrumButton
        function PowerSpectrumButtonPushed(app, event)
            delete(app.UIAxes1);
            
            delete(app.UIAxes2);
            delete(app.UIAxes3);
            delete(app.UIAxes4);
            delete(app.UIAxes5);
            delete(app.UIAxes6);

% Define the frequency and time parameters of the EEG signal
fs = 256; % Sampling frequency of EEG signal
t = 0:1/fs:(length(app.EEG_Data)-50)/fs; % Time vector for EEG signal

% Calculate the power spectral density (PSD) of the EEG signal
nfft = 2^nextpow2(length(app.EEG_Data(1,:))); % Length of FFT
psd = abs(fft(app.EEG_Data, nfft)).^2/length(app.EEG_Data)/fs; % Power spectral density
f = fs/2*linspace(0,1,nfft/2+1); % Frequency vector for PSD

% Identify the dominant frequency bands in the PSD
delta_band = find(f >= 0.5 & f <= 4);
theta_band = find(f > 4 & f <= 8);
alpha_band = find(f > 8 & f <= 13);
beta_band = find(f > 13 & f <= 30);
gamma_band = find(f > 30 & f <= 45);

% Calculate the spectral power in each frequency band
delta_power = sum(psd(delta_band));
theta_power = sum(psd(theta_band));
alpha_power = sum(psd(alpha_band));
beta_power = sum(psd(beta_band));
gamma_power = sum(psd(gamma_band));


% Plot the spectral power in each frequency band
cla(app.UIAxes);
bar(app.UIAxes,[delta_power, theta_power, alpha_power, beta_power, gamma_power]);
xticklabels({'Delta', 'Theta', 'Alpha', 'Beta', 'Gamma'});
ylabel('Spectral Power');

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.EditField.Value = ' EEG Signal Analysis and Processing  ';
            app.RowSignalButton = uibutton(app.UIFigure, 'push');
            app.RowSignalButton.ButtonPushedFcn = createCallbackFcn(app, @RowSignalButtonPushed, true);
            app.RowSignalButton.Text = 'Row Signal';
            app.filterSignalButton = uibutton(app.UIFigure, 'push');
            app.filterSignalButton.ButtonPushedFcn = createCallbackFcn(app, @filterSignalButtonPushed, true);
            app.filterSignalButton.Text = 'filter Signal';
            app.BandsButton = uibutton(app.UIFigure, 'push');
            app.BandsButton.ButtonPushedFcn = createCallbackFcn(app, @bands, true);
            app.BandsButton.Text = 'Bands ';
            app.TimeDomainSignalAndFrequencyDomainButton = uibutton(app.UIFigure, 'push');
            app.TimeDomainSignalAndFrequencyDomainButton.ButtonPushedFcn = createCallbackFcn(app, @timeDomandFreaDom, true);
            app.TimeDomainSignalAndFrequencyDomainButton.Text = {'Time Domain Signal '; 'And '; 'Frequency Domain'};
            app.DiscreteWaveletTransformButton = uibutton(app.UIFigure, 'push');
            app.DiscreteWaveletTransformButton.ButtonPushedFcn = createCallbackFcn(app, @DWT, true);
            app.DiscreteWaveletTransformButton.Text = {'Discrete Wavelet '; 'Transform'};
            app.ChooseRowDropDownLabel = uilabel(app.UIFigure);
            app.ChooseRowDropDownLabel.Text = 'Choose Row';
            app.ChooseRowDropDown = uidropdown(app.UIFigure);
            app.ChooseRowDropDown.ClickedFcn = createCallbackFcn(app, @ChooseRowDropDownClicked, true);
            app.ChoosedatasetButton = uibutton(app.UIFigure, 'push');
            app.ChoosedatasetButton.ButtonPushedFcn = createCallbackFcn(app, @ChoosedatasetButtonPushed, true);
            app.ChoosedatasetButton.Text = 'Choose dataset';
            app.MorphologicalfilterButton = uibutton(app.UIFigure, 'push');
            app.MorphologicalfilterButton.ButtonPushedFcn = createCallbackFcn(app, @MorphologicalfilterButtonPushed, true);
            app.MorphologicalfilterButton.Text = 'Morphological filter';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Screenshot 2023-04-08 141752.png');
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'Screenshot 2023-04-02 125630.png');
            app.PowerSpectrumButton = uibutton(app.UIFigure, 'push');
            app.PowerSpectrumButton.ButtonPushedFcn = createCallbackFcn(app, @PowerSpectrumButtonPushed, true);
            app.PowerSpectrumButton.Text = 'Power Spectrum';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CP

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end